classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        INTRODUCTIONButtonGroup    matlab.ui.container.ButtonGroup
        ContactusButton            matlab.ui.control.Button
        TermsandconditionsButton   matlab.ui.control.Button
        AboutusButton              matlab.ui.control.Button
        HomeButton                 matlab.ui.control.Button
        FeedbackButton             matlab.ui.control.Button
        ADMINButton                matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        HOMETab                    matlab.ui.container.Tab
        SEARCHButton               matlab.ui.control.Button
        TextArea_2                 matlab.ui.control.TextArea
        EditField                  matlab.ui.control.EditField
        CPROGRAMMINGTab            matlab.ui.container.Tab
        TextArea                   matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        resultspaceEditField       matlab.ui.control.EditField
        resultspaceEditFieldLabel  matlab.ui.control.Label
        searchbarEditField         matlab.ui.control.EditField
        searchbarEditFieldLabel    matlab.ui.control.Label
        HTMLTab                    matlab.ui.container.Tab
        TextArea_3                 matlab.ui.control.TextArea
        SEARCHButton_2             matlab.ui.control.Button
        EditField_2                matlab.ui.control.EditField
        GITTab                     matlab.ui.container.Tab
        SEARCHButton_5             matlab.ui.control.Button
        TextArea_5                 matlab.ui.control.TextArea
        EditField_4                matlab.ui.control.EditField
        BookshelfTab               matlab.ui.container.Tab
        GITButton                  matlab.ui.control.Button
        HTMLButton                 matlab.ui.control.Button
        CPROGRAMMINGButton         matlab.ui.control.Button
        ApplicationdesignedanddevelopedbyPrachiTripathiLabel  matlab.ui.control.Label
        CodeXLR8Label              matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        UserPrompt='login first';
TitleBar='LOGIN';
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'WELCOME TO CODE ACCELERATE , YOU  NEED TO LOGIN FIRST');

UserInput= inputdlg (UserPrompt , TitleBar , 1 , string);
if isempty(UserInput)
return;
end
UserInput=char(UserInput);

b='007';
c=strcmp(UserInput,b);
if c==1
%     msgbox('success');
msgbox('WELCOME TO CODE XLR8.')
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH SUCCESSFUL');
else
msgbox('failed');
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH UNSUCCESSFUL');
delete(app);
end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            txt= app.searchbarEditField.Value;
%input check and anwering
if(txt=="write a program to find factorial"||txt=="program to calculate factorial")
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO FIND FACTORIAL OF A NUMBER,HOPE THIS HELPS');

app.TextArea.Value {1}='//factorial program' ;
app.TextArea.Value {2}='#include<stdio.h>';
app.TextArea.Value {3}='#include<conio.h>';
app.TextArea.Value {4}='void main()' ;
app.TextArea.Value {5}= '{' ;
app.TextArea.Value {6}='int n,f=1;';
app.TextArea.Value {7}='printf("enter any number");';
app.TextArea.Value {8}='scanf("%d",&n);';
app.TextArea.Value {9}='while(n>0){';
app.TextArea.Value {10}='f=f*n';
app.TextArea.Value {11}='n--';
app.TextArea.Value {12}='}';
app.TextArea.Value {13}='printf("%d",f);';
app.TextArea.Value {14}='getch();';
app.TextArea.Value {15}='}' ;


elseif(txt=="vowels and consonent program"||txt=="vowel or consonent")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO FIND vovels and consonents ,hope this helps');

app.TextArea.Value {1}='#include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}=  'char c;';
app.TextArea.Value {4}=  'int lowercase_vowel, uppercase_vowel;';
app.TextArea.Value {5}= 'printf("Enter an alphabet: ");';
app.TextArea.Value {6}='scanf("%c", &c);';
app.TextArea.Value {7}=  '// evaluates to 1 if variable c is a lowercase vowel';
app.TextArea.Value {8}= 'lowercase_vowel = (c == ''a ''|| c == ''e ''|| c == ''i'' || c == ''o'' || c == ''u'');';
app.TextArea.Value {9}='// evaluates to 1 if variable c is a uppercase vowel';
app.TextArea.Value {10}=  'uppercase_vowel = (c == ''A'' || c == ''E'' || c == ''I'' || c == ''O'' || c == ''U'');';
app.TextArea.Value {11}= '// evaluates to 1 (true) if c is a vowel';
app.TextArea.Value {12}= 'if (lowercase_vowel || uppercase_vowel);';
app.TextArea.Value {13}='  printf("%c is a vowel.", c);';
app.TextArea.Value {14}=  'else';
app.TextArea.Value {15}= '   printf("%s is a consonant.", c);';
app.TextArea.Value {16}= 'return 0;';
app.TextArea.Value {17}='}';


elseif(txt=="swap two numbers"||txt=="number swaping")
     NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO swap two numbers , hope this helps ');
  
app.TextArea.Value {1}=' #include<stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}='  double first, second, temp;';
app.TextArea.Value {4}=' printf("Enter first number: ");';
app.TextArea.Value {5}=' scanf("%lf", &first);';
app.TextArea.Value {6}=' printf("Enter second number: ");';
app.TextArea.Value {7}=' scanf("%lf", &second);';
app.TextArea.Value {8}='// value of first is assigned to temp';
app.TextArea.Value {9}='  temp = first;';
app.TextArea.Value {10}=' // value of second is assigned to first';
app.TextArea.Value {11}=' first = second;';
app.TextArea.Value {12}=' // value of temp (initial value of first) is assigned to second';
app.TextArea.Value {13}=' second = temp;';
app.TextArea.Value {14}='// %.2lf displays number up to 2 decimal points';
app.TextArea.Value {15}='  printf("\nAfter swapping, first number = %.2lf\n", first);';
app.TextArea.Value {16}=' printf("After swapping, second number = %.2lf", second);';
app.TextArea.Value {17}='  return 0;';
app.TextArea.Value {18}='}';

   
elseif(txt=="add two numbers"||txt=="addition program")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO add two numbers, hope this helps ');
app.TextArea.Value {1}='  #include <stdio.h>';
app.TextArea.Value {2}='int main() {';    
app.TextArea.Value {3}=' int number1, number2, sum;';
app.TextArea.Value {4}=' printf("Enter two integers: ");';
app.TextArea.Value {5}=' scanf("%d %d", &number1, &number2);';
app.TextArea.Value {6}='// calculate the sum';
app.TextArea.Value {7}='  sum = number1 + number2;     '; 
app.TextArea.Value {8}='  printf("%d + %d = %d", number1, number2, sum);';
app.TextArea.Value {9}='   return 0;';
app.TextArea.Value {10}='}';

   
elseif(txt=="calculator"||txt=="calculator program")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO make a calculator, hope this helps ');

app.TextArea.Value {1}='#include <stdio.h>';
app.TextArea.Value {2}='int main(){';
app.TextArea.Value {3}=' char Operator;';
app.TextArea.Value {4}=' float num1, num2, result = 0;';
app.TextArea.Value {5}=' printf("';
app.TextArea.Value {6}=' Enter any one operator like +, -, *, / : ");';
app.TextArea.Value {7}='   scanf("%c", &Operator);';
app.TextArea.Value {8}=' printf("Enter the values of Operands num1 and num2'; 
app.TextArea.Value {9}=': ");';
app.TextArea.Value {10}=' scanf("%f%f", &num1, &num2);';
app.TextArea.Value {11}=' switch(Operator){';
app.TextArea.Value {12}=' case ''+'': result = num1 + num2;';
app.TextArea.Value {13}='      break;';
app.TextArea.Value {14}=' case ''-'': result = num1 - num2;';
app.TextArea.Value {14}=' break;';
app.TextArea.Value {15}=' case ''*'': result = num1 * num2;';
app.TextArea.Value {16}='   break;';
app.TextArea.Value {16}='    case ''/'': result = num1 / num2;';
app.TextArea.Value {17}='       break;';
app.TextArea.Value {18}=' default: printf("';
app.TextArea.Value {19}='Invalid Operator ");';
app.TextArea.Value {20}='  }';
app.TextArea.Value {21}='  printf("The value = %f", result);';
app.TextArea.Value {22}=' return 0;';
app.TextArea.Value {23}='}';

elseif(txt=="reverse no"||txt=="reverse")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO reverse numbers, hope this helps ');
app.TextArea.Value {1}='  #include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}='int n, reverse = 0, remainder;';
app.TextArea.Value {4}='printf("Enter an integer: ");';
app.TextArea.Value {5}=' scanf("%d", &n);';
app.TextArea.Value {6}='  while (n != 0) {';
app.TextArea.Value {7}='  remainder = n % 10;';
app.TextArea.Value {8}=' reverse = reverse * 10 + remainder;';
app.TextArea.Value {9}=' n /= 10;';
app.TextArea.Value {10}=' }';
app.TextArea.Value {11}='  printf("Reversed number = %d", reverse);';
app.TextArea.Value {12}='return 0;';
app.TextArea.Value {13}='}';
   
elseif(txt=="palindrome no"||txt=="palindrome")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find palindrome number, hope this helps ');
app.TextArea.Value {1}='   #include<stdio.h>  ';
app.TextArea.Value {2}='int main()    ';
app.TextArea.Value {3}='{    ';
app.TextArea.Value {4}='int n,r,sum=0,temp;';    
app.TextArea.Value {5}='printf("enter the number=");';    
app.TextArea.Value {6}='scanf("%d",&n);    ';
app.TextArea.Value {7}='temp=n;    ';
app.TextArea.Value {8}='while(n>0)   '; 
app.TextArea.Value {9}='{    ';
app.TextArea.Value {10}='r=n%10;';    
app.TextArea.Value {11}='sum=(sum*10)+r; ';   
app.TextArea.Value {12}='n=n/10;    ';
app.TextArea.Value {13}='}    ';
app.TextArea.Value {14}='if(temp==sum)';    
app.TextArea.Value {15}='printf("palindrome number ");  ';  
app.TextArea.Value {16}='else    ';
app.TextArea.Value {17}='printf("not palindrome"); ';  
app.TextArea.Value {18}='return 0;  ';
app.TextArea.Value {19}='}   ';
   
elseif(txt=="largest number in an array"||txt=="largest array number")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find largest number, hope this helps ');
app.TextArea.Value {1}='  #include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}='int n;';
app.TextArea.Value {4}='double arr[100];';
app.TextArea.Value {5}=' printf("Enter the number of elements (1 to 100): ");';
app.TextArea.Value {6}='scanf("%d", &n);';
app.TextArea.Value {7}=' for (int i = 0; i < n; ++i) {';
app.TextArea.Value {8}='  printf("Enter number%d: ", i + 1);';
app.TextArea.Value {9}='scanf("%lf", &arr[i]);';
app.TextArea.Value {10}=' }';
app.TextArea.Value {11}='// storing the largest number to arr[0]';
app.TextArea.Value {12}='for (int i = 1; i < n; ++i) {';
app.TextArea.Value {13}=' if (arr[0] < arr[i]) {';
app.TextArea.Value {14}=' arr[0] = arr[i];';
app.TextArea.Value {15}=' }';
app.TextArea.Value {16}=' }';
app.TextArea.Value {17}=' printf("Largest element = %.2lf", arr[0]);';
app.TextArea.Value {18}='return 0;';
app.TextArea.Value {19}='}';
elseif(txt=="prime no"||txt=="prime number")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find prime or not, hope this helps ');
app.TextArea.Value {1}='  #include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}='  int n, i, flag = 0;';
app.TextArea.Value {4}=' printf("Enter a positive integer: ");';
app.TextArea.Value {5}=' scanf("%d", &n);';
app.TextArea.Value {6}=' // 0 and 1 are not prime numbers';
app.TextArea.Value {7}=' // change flag to 1 for non-prime number';
app.TextArea.Value {8}='if (n == 0 || n == 1)';
app.TextArea.Value {9}=' flag = 1;';
app.TextArea.Value {10}='for (i = 2; i <= n / 2; ++i) {';
app.TextArea.Value {11}=' // if n is divisible by i, then n is not prime';
app.TextArea.Value {12}='  // change flag to 1 for non-prime number';
app.TextArea.Value {13}='  if (n % i == 0) {';
app.TextArea.Value {14}='   flag = 1;';
app.TextArea.Value {15}='  break;';
app.TextArea.Value {16}='  }';
app.TextArea.Value {17}='  }';
app.TextArea.Value {18}=' // flag is 0 for prime numbers';
app.TextArea.Value {19}=' if (flag == 0)';
app.TextArea.Value {20}=' printf("%d is a prime number.", n);';
app.TextArea.Value {21}=' else';
app.TextArea.Value {22}=' printf("%d is not a prime number.", n);';
app.TextArea.Value {23}='  return 0;';
app.TextArea.Value {24}='}';

elseif(txt=="factors of a number"||txt=="number factors")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find factors of a number , hope this helps ');
app.TextArea.Value {1}='  #include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}='  int num, i;';
app.TextArea.Value {4}=' printf("Enter a positive integer: ");';
app.TextArea.Value {5}='scanf("%d", &num);';
app.TextArea.Value {6}=' printf("Factors of %d are: ", num);';
app.TextArea.Value {7}=' for (i = 1; i <= num; ++i) {';
app.TextArea.Value {8}=' if (num % i == 0) {';
app.TextArea.Value {9}=' printf("%d ", i);';
app.TextArea.Value {10}=' }';
app.TextArea.Value {11}=' }';
app.TextArea.Value {12}=' return 0;';
app.TextArea.Value {13}='}';

   
elseif(txt=="greatest common divisor"||txt=="gcd")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find greatest common divisor, hope this helps ');
app.TextArea.Value {1}='#include <stdio.h>';
app.TextArea.Value {2}='int main()';
app.TextArea.Value {3}='{';
app.TextArea.Value {4}='  int n1, n2, i, gcd;';
app.TextArea.Value {5}='  printf("Enter two integers: ");';
app.TextArea.Value {6}='  scanf("%d %d", &n1, &n2);';
app.TextArea.Value {7}='  for(i=1; i <= n1 && i <= n2; ++i)';
app.TextArea.Value {8}='  {';
app.TextArea.Value {9}='   // Checks if i is factor of both integers';
app.TextArea.Value {10}=' if(n1%i==0 && n2%i==0)';
app.TextArea.Value {11}='   gcd = i;';
app.TextArea.Value {12}='  }';
app.TextArea.Value {13}=' printf("G.C.D of %d and %d is %d", n1, n2, gcd);';
app.TextArea.Value {14}='  return 0;';
app.TextArea.Value {15}='}';

elseif(txt=="armstrong number"||txt=="armstrong")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find amstrong numbers, hope this helps ');
app.TextArea.Value {1}='#include <stdio.h>';
app.TextArea.Value {2}='int main() {';
app.TextArea.Value {3}=' int num, originalNum, remainder, result = 0;';
app.TextArea.Value {4}='   printf("Enter a three-digit integer: ");';
app.TextArea.Value {5}=' scanf("%d", &num);';
app.TextArea.Value {6}='originalNum = num;';
app.TextArea.Value {7}=' while (originalNum != 0) {';
app.TextArea.Value {8}='  // remainder contains the last digit';
app.TextArea.Value {9}=' remainder = originalNum % 10;';
app.TextArea.Value {10}='  result += remainder * remainder * remainder;';
app.TextArea.Value {11}=' // removing last digit from the orignal number';
app.TextArea.Value {12}=' originalNum /= 10;';
app.TextArea.Value {13}='    }';
app.TextArea.Value {14}=' if (result == num)';
app.TextArea.Value {15}='  printf("%d is an Armstrong number.", num);';
app.TextArea.Value {16}=' else';
app.TextArea.Value {17}='    printf("%d is not an Armstrong number.", num);';
app.TextArea.Value {18}='  return 0;';
app.TextArea.Value {19}='}';

elseif(txt=="fibonacci series"||txt=="fibonacci")
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'HERE IS A PROGRAM TO find fibonacci series , hope this helps ');
app.TextArea.Value {1}='// Fibonacci Series using Recursion';
app.TextArea.Value {2}='#include <stdio.h>';
app.TextArea.Value {3}='int fib(int n)';
app.TextArea.Value {4}='{';
app.TextArea.Value {5}='	if (n <= 1)';
app.TextArea.Value {6}='	return n;';
app.TextArea.Value {7}='	return fib(n - 1) + fib(n - 2);';
app.TextArea.Value {8}='}';
app.TextArea.Value {9}='int main()';
app.TextArea.Value {10}='{';
app.TextArea.Value {11}='	int n = 9;';
app.TextArea.Value {12}='	printf("%d", fib(n));';
app.TextArea.Value {13}='	getchar();';
app.TextArea.Value {14}='	return 0;';
app.TextArea.Value {15}='}';

 %interviev questions or qna
elseif(txt=="what is c programing"||txt=="c programming")

app.TextArea.Value {1}='C programming language, the pioneer of programming languages, is a procedural programming language. Dennis Ritchie created it as a system programming language for writing operating systems. It is one of the most popular programming languages because of its structure, high-level abstraction, machine-independent feature, etc. and is a great starting point for anyone wanting to get into coding. ';

app.TextArea.Value {2}='C is also used a lot in low-level system programming, embedded systems, and hardware. It has also been heavily optimized over the years and is still used to write sophisticated software such as the FreeBSD operating system and the XNU kernel. Low-level memory access, a small collection of keywords, and a clean style are all qualities that make the C language excellent for system programmings, such as operating system or compiler development.'; 

app.TextArea.Value {3}='C is a low-level programming language that can be directly interfaced with the processor. It offers minimal abstraction and maximal control, making it an attractive option for developers who want to write efficient code. ';
elseif(txt=="features of c programming"||txt=="c programming features")

app.TextArea.Value {1}='Some features of the C language are- ';
app.TextArea.Value {2}='It is Simple And Efficient. ';
app.TextArea.Value {3}='C language is portable or Machine Independent.';
app.TextArea.Value {4}='C is a mid-level Programming Language. ';
app.TextArea.Value {5}='It is a structured Programming Language.';
app.TextArea.Value {6}='It has a function-rich library. ';
app.TextArea.Value {7}='Dynamic Memory Management.';
app.TextArea.Value {8}='C is super fast.';
app.TextArea.Value {9}='We can use pointers in C.';
app.TextArea.Value {10}='It is extensible.';

elseif(txt=="tokens of c programming"||txt=="c programming tokens")
app.TextArea.Value {1}='The individual elements of a program are called Tokens. There are following 6 types of tokens are available in C:';
app.TextArea.Value {2}='Identifiers';
app.TextArea.Value {3}='Keywords';
app.TextArea.Value {4}='Constants';
app.TextArea.Value {5}='Operators';
app.TextArea.Value {6}='Special Characters';
app.TextArea.Value {7}='Strings';

elseif(txt=="constant of c programming"||txt=="c programming constants"||txt=="constants"||txt=="constants")
app.TextArea.Value {1}='A constant is a name given to the variable whose values cant be altered or changed. A constant is very similar to variables in the C programming language, but it can hold only a single variable during the execution of a program.';
  elseif(txt=="variablers"||txt=="variables in c")
  app.TextArea.Value {1}=' A variable is a name of the memory location. It is used to store data. Its value can be changed, and it can be reused many times.';

app.TextArea.Value {2}='It is a way to represent memory location through symbol so that it can be easily identified.';  
elseif(txt=="identifiers"||txt=="identifiers in c")
app.TextArea.Value {1}='   C identifiers represent the name in the C program, for example, variables, functions, arrays, structures, unions, labels, etc. An identifier can be composed of letters such as uppercase, lowercase letters, underscore, digits, but the starting letter should be either an alphabet or an underscore. If the identifier is not used in the external linkage, then it is called as an internal identifier. If the identifier is used in the external linkage, then it is called as an external identifier.';
app.TextArea.Value {2}='We can say that an identifier is a collection of alphanumeric characters that begins either with an alphabetical character or an underscore, which are used to represent various programming elements such as variables, functions, arrays, structures, unions, labels, etc. There are 52 alphabetical characters (uppercase and lowercase), underscore character, and ten numerical digits (0-9) that represent the identifiers. There is a total of 63 alphanumerical characters that represent the identifiers.';
app.TextArea.Value {3}='Rules for constructing C identifiers';
app.TextArea.Value {4}='The first character of an identifier should be either an alphabet or an underscore, and then it can be followed by any of the character, digit, or underscore.';
app.TextArea.Value {5}='It should not begin with any numerical digit.';
app.TextArea.Value {6}='In identifiers, both uppercase and lowercase letters are distinct. Therefore, we can say that identifiers are case sensitive.';
app.TextArea.Value {7}='Commas or blank spaces cannot be specified within an identifier.';
app.TextArea.Value {8}='Keywords cannot be represented as an identifier.';
app.TextArea.Value {9}='The length of the identifiers should not be more than 31 characters.';
app.TextArea.Value {10}='Identifiers should be written in such a way that it is meaningful, short, and easy to read.';

elseif(txt=="keywords"||txt=="keywords in c")
app.TextArea.Value {1}='A keyword is a reserved word. You cannot use it as a variable name, constant name, etc. There are only 32 reserved words (keywords) in the C language.';
app.TextArea.Value {2}='A list of 32 keywords in the c language is given below:';
app.TextArea.Value {3}='auto	break	case	char	const	continue	default	do';
app.TextArea.Value {4}='double	else	enum	extern	float	for	goto	if';
app.TextArea.Value {5}='int	long	register	return	short	signed	sizeof	static';
app.TextArea.Value {6}='struct	switch	typedef	union	unsigned	void	volatile	while';
elseif(txt=="operators"||txt=="operators in c")
   app.TextArea.Value {1}=' An operator is simply a symbol that is used to perform operations. There can be many types of operations like arithmetic, logical, bitwise, etc.';

app.TextArea.Value {2}='There are following types of operators to perform different types of operations in C language.';

app.TextArea.Value {3}='Arithmetic Operators';
app.TextArea.Value {4}='Relational Operators';
app.TextArea.Value {5}='Shift Operators';
app.TextArea.Value {6}='Logical Operators';
app.TextArea.Value {7}='Bitwise Operators';
app.TextArea.Value {8}='Ternary or Conditional Operators';
app.TextArea.Value {9}='Assignment Operator';

elseif(txt=="array"||txt=="what is an array")
    app.TextArea.Value {1}='Arrays are used to store multiple values in a single variable, instead of declaring separate variables for each value.';
app.TextArea.Value {2}='To create an array, define the data type (like int) and specify the name of the array followed by square brackets [].';
app.TextArea.Value {3}='To insert values to it, use a comma-separated list, inside curly braces:';
app.TextArea.Value {4}='int myNumbers[] = {25, 50, 75, 100};';

elseif(txt=="pointer"||txt=="what is a pointer")
  app.TextArea.Value {1}='  A pointer is a variable that stores the memory address of another variable as its value.';
app.TextArea.Value {2}='A pointer variable points to a data type (like int) of the same type, and is created with the * operator.';
app.TextArea.Value {3}='The address of the variable you are working with is assigned to the pointer:';

elseif(txt=="structure"||txt=="what is a structure")
   app.TextArea.Value {1}=' Structures (also called structs) are a way to group several related variables into one place. Each variable in the structure is known as a member of the structure.';
app.TextArea.Value {2}='Unlike an array, a structure can contain many different data types (int, float, char, etc.).';
app.TextArea.Value {3}='Create a Structure';
app.TextArea.Value {4}='You can create a structure by using the struct keyword and declare each of its members inside curly braces:';
app.TextArea.Value {5}='struct MyStructure {   // Structure declaration';
  app.TextArea.Value {6}='int myNum;           // Member (int variable)';
app.TextArea.Value {7}='  char myLetter;       // Member (char variable)';
app.TextArea.Value {8}='}; // End the structure with a semicolon';

elseif(txt=="union"||txt=="what is a union")
app.TextArea.Value {1}='A union is a special data type available in C that allows to store different data types in the same memory location. You can define a union with many members, but only one member can contain a value at any given time. Unions provide an efficient way of using the same memory location for multiple-purpose.';
app.TextArea.Value {2}='Defining a Union';
app.TextArea.Value {3}='union [union tag] {';
app.TextArea.Value {4}='  member definition;';
app.TextArea.Value {5}=' member definition;';
app.TextArea.Value {6}='...';
app.TextArea.Value {7}=' member definition;';
app.TextArea.Value {8}='} [one or more union variables];'; 
elseif(txt=="if else"||txt=="what is if else")
    app.TextArea.Value {1}='Use if to specify a block of code to be executed, if a specified condition is true';
app.TextArea.Value {2}='Use else to specify a block of code to be executed, if the same condition is false';
app.TextArea.Value {3}='Use else if to specify a new condition to test, if the first condition is false';
app.TextArea.Value {4}='Use switch to specify many alternative blocks of code to be executed';

elseif(txt=="switch casse"||txt=="what is switch case")
   app.TextArea.Value {1}=' The switch statement in C is an alternate to if-else-if ladder statement which allows us to execute multiple operations for the different possibles values of a single variable called switch variable. Here, We can define various statements in the multiple cases for the different values of a single variable.';
app.TextArea.Value {2}='The syntax of switch statement in c language is given below:';
app.TextArea.Value {3}='switch(expression){    ';
app.TextArea.Value {4}='case value1:    ';
 app.TextArea.Value {5}='//code to be executed;';    
app.TextArea.Value {6}=' break;  //optional  ';
app.TextArea.Value {7}='case value2:    ';
app.TextArea.Value {8}=' //code to be executed;';    
app.TextArea.Value {9}=' break;  //optional  ';
app.TextArea.Value {10}='......    ';
    
app.TextArea.Value {11}='default:    '; 
app.TextArea.Value {12}=' code to be executed if all cases are not matched;   '; 
app.TextArea.Value {13}='}    ';

elseif(txt=="main"||txt=="what is main")
 app.TextArea.Value = '  Every C program has a primary function that must be named main . The main function serves as the starting point for program execution. It usually controls program execution by directing the calls to other functions in the program.';

elseif(txt=="preprocessor directive"||txt=="what is preprocessor directive")
  app.TextArea.Value =  ' Preprocessor directives are lines of the source file where the first non-whitespace character is # , which distinguishes them from other lines of text. The effect of each preprocessor directive is a change to the text and the result is a transformation of the text that does not contain the directives nor comments.';
elseif(txt=="macros"||txt=="what is macros")
    app.TextArea.Value = ' Macro in c is defined by the #define directive. Macro is a name given to a piece of code, so whenever the compiler encounters a macro in a program, it will replace it with the macro value. In the macro definition, the macros are not terminated by the semicolon(;)';
elseif(txt=="dynamic memory allocation"||txt=="what is dynamic memmory allocation")
 app.TextArea.Value {1}='  The concept of dynamic memory allocation in c language enables the C programmer to allocate memory at runtime. Dynamic memory allocation in c language is possible by 4 functions of stdlib.h header file.';
app.TextArea.Value {2}=' malloc()';
app.TextArea.Value {3}='calloc()';
app.TextArea.Value {4}='realloc()';
app.TextArea.Value {5}='free()';

elseif(txt=="function"||txt=="what is function")
     app.TextArea.Value = 'In c, we can divide a large program into the basic building blocks known as function. The function contains the set of programming statements enclosed by {}. A function can be called multiple times to provide reusability and modularity to the C program. In other words, we can say that the collection of functions creates a program. ';
elseif(txt=="recursion"||txt=="what is recursion")
  app.TextArea.Value =  ' Any function which calls itself is called recursive function, and such function calls are called recursive calls. Recursion involves several numbers of recursive calls. However, it is important to impose a termination condition of recursion. ';
elseif(txt=="call by value"||txt=="what is call by value")
    app.TextArea.Value = ' The call by value method of passing arguments to a function copies the actual value of an argument into the formal parameter of the function. In this case, changes made to the parameter inside the function have no effect on the argument.';
elseif(txt=="call by reference"||txt=="what is call by reference")
  app.TextArea.Value =  ' The call by reference method of passing arguments to a function copies the address of an argument into the formal parameter. Inside the function, the address is used to access the actual argument used in the call. It means the changes made to the parameter affect the passed argument.';
elseif(txt=="function prototype"||txt=="what is function prototype")
app.TextArea.Value = 'Function prototype tells the compiler about a number of parameters function takes data-types of parameters, and return type of function. By using this information, the compiler cross-checks function parameters and their data type with function definition and function call.';
elseif(txt=="history of c programming"||txt=="what is history of c")
   app.TextArea.Value {1}=' C programming language was developed in 1972 by Dennis Ritchie at bell laboratories of AT&T (American Telephone & Telegraph), located in the U.S.A.';
app.TextArea.Value {2}='Dennis Ritchie is known as the founder of the c language.';
app.TextArea.Value {3}='It was developed to overcome the problems of previous languages such as B, BCPL, etc.';

elseif(txt=="datatype"||txt=="what is datatype")
app.TextArea.Value {1}=' A data type specifies the type of data that a variable can store such as integer, floating, character, etc.';
app.TextArea.Value {2}='C Data Types';
app.TextArea.Value {3}='There are the following data types in C language.';
app.TextArea.Value {4}='Basic -Data Type=	int, char, float, double';
app.TextArea.Value {5}='Derived Data Type=	array, pointer, structure, union';
app.TextArea.Value {6}='Enumeration Data Type=	enum';
app.TextArea.Value {7}='Void Data Type	=void';
elseif(txt=="storage class"||txt=="what is storage class")
   app.TextArea.Value {1}=' Storage classes in C are used to determine the lifetime, visibility, memory location, and initial value of a variable. There are four types of storage classes in C';
app.TextArea.Value {2}='Automatic';
app.TextArea.Value {3}='External';
app.TextArea.Value {4}='Static';
app.TextArea.Value {5}='Register';

elseif(txt=="for loop"||txt=="what is for loop")
app.TextArea.Value {1}=' When you know exactly how many times you want to loop through a block of code, use the for loop instead of a while loop:';
app.TextArea.Value {2}='Syntax';
app.TextArea.Value {3}='for (statement 1; statement 2; statement 3) {';
app.TextArea.Value {4}='  // code block to be executed';
app.TextArea.Value {5}='}';
app.TextArea.Value {6}='Statement 1 is executed (one time) before the execution of the code block.';
app.TextArea.Value {7}='Statement 2 defines the condition for executing the code block.';
app.TextArea.Value {8}='Statement 3 is executed (every time) after the code block has been executed.';

elseif(txt=="while loop"||txt=="what is while loop")
  app.TextArea.Value {1}='  The while loop loops through a block of code as long as a specified condition is true:';
app.TextArea.Value {2}='Syntax';
app.TextArea.Value {3}='while (condition) {';
 app.TextArea.Value {4}=' // code block to be executed';
app.TextArea.Value {5}='}';

elseif(txt=="do while loop"||txt=="what is do while loop")
  app.TextArea.Value {1}='   The do/while loop is a variant of the while loop. This loop will execute the code block once, before checking if the condition is true, then it will repeat the loop as long as the condition is true.';

app.TextArea.Value {2}=' Syntax';
app.TextArea.Value {3}=' do {';
app.TextArea.Value {4}='   // code block to be executed';
app.TextArea.Value {5}=' }';
app.TextArea.Value {6}=' while (condition);';
elseif(txt=="loops"||txt=="what is loop")
app.TextArea.Value {1}='   Loops can execute a block of code as long as a specified condition is reached.';
app.TextArea.Value {2}='Loops are handy because they save time, reduce errors, and they make code more readable.';
elseif(txt=="break and continue"||txt=="what is break and continue")
app.TextArea.Value {1}=' The break statement can also be used to jump out of a loop.';
app.TextArea.Value {2}=' This example jumps out of the for loop when i is equal to 4:';
app.TextArea.Value {3}=' Example';
app.TextArea.Value {4}=' int i;';
app.TextArea.Value {5}=' for (i = 0; i < 10; i++) {';
app.TextArea.Value {6}=' if (i == 4) {';
app.TextArea.Value {7}='  break;';
app.TextArea.Value {8}='  }';
app.TextArea.Value {9}='  printf("%d\n", i);';
app.TextArea.Value {10}=' }';
app.TextArea.Value {11}=' Continue';
app.TextArea.Value {12}=' The continue statement breaks one iteration (in the loop), if a specified condition occurs, and continues with the next iteration in the loop.';
app.TextArea.Value {13}=' This example skips the value of 4:';
app.TextArea.Value {14}=' Example';
app.TextArea.Value {15}=' int i;';
app.TextArea.Value {16}=' for (i = 0; i < 10; i++) {';
app.TextArea.Value {17}='  if (i == 4) {';
app.TextArea.Value {18}=' continue;';
app.TextArea.Value {19}='   }';
app.TextArea.Value {20}='  printf("%d\n", i);';
app.TextArea.Value {21}=' }';

elseif(txt=="file handeling"||txt=="what is file handeling")

app.TextArea.Value {1}='File handling in C enables us to create, update, read, and delete the files stored on the local file system through our C program. The following operations can be performed on a file.';

app.TextArea.Value {2}='Creation of the new file';
app.TextArea.Value {3}='Opening an existing file';
app.TextArea.Value {4}='Reading from the file';
app.TextArea.Value {5}='Writing to the file';
app.TextArea.Value {6}='Deleting the file';
%Functions for file handling
app.TextArea.Value {7}='There are many functions in the C library to open, read, write, search and close the file. A list of file functions are given below:';
app.TextArea.Value {8}='No.	Function	Description';
app.TextArea.Value {9}='1	fopen()	opens new or existing file';
app.TextArea.Value {10}='2	fprintf()	write data into the file';
app.TextArea.Value {11}='3	fscanf()	reads data from the file';
app.TextArea.Value {12}='4	fputc()	writes a character into the file';
app.TextArea.Value {13}='5	fgetc()	reads a character from file';
app.TextArea.Value {14}='6	fclose()	closes the file';
app.TextArea.Value {15}='7	fseek()	sets the file pointer to given position';
app.TextArea.Value {16}='8	fputw()	writes an integer to file';
app.TextArea.Value {17}='9	fgetw()	reads an integer from file';
app.TextArea.Value {18}='10	ftell()	returns current position';
app.TextArea.Value {19}='11	rewind()	sets the file pointer to the beginning of the file';


else
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,' my apology , i dont have a solution for this at the momment ');
    app.TextArea.Value ="cant find a solution";

end
% app.resultspaceEditField.Value{1}='showing results for';
   app.resultspaceEditField.Value= txt;    


        end

        % Callback function
        function PanelButtonDown(app, event)
      

        end

        % Callback function
        function GITButtonPushed(app, event)
      
         
        end

        % Value changed function: resultspaceEditField
        function resultspaceEditFieldValueChanged(app, event)
       
            
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
           
            
        end

        % Button pushed function: ADMINButton
        function ADMINButtonPushed(app, event)
UserPrompt='login first';

TitleBar='text to speech';

UserInput= inputdlg (UserPrompt , TitleBar , 1 , string);
if isempty(UserInput)
return;
end
UserInput=char(UserInput);
% NET.addAssembly('System.Speech');
% obj=System.Speech.Synthesis.SpeechSynthesizer;
% obj.Volume=100;
% Speak(obj,UserInput);
b='007';
c=strcmp(UserInput,b);
if c==1
%     msgbox('success');
msgbox(['WELCOME ADMIN .' ...
'VERSION=1.0' ...
'DATE CREATED 15/04/2003' ...
'SUBJECTS =C,HTML,GIT,CSS'])
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH SUCCESSFUL');
else
msgbox('failed');
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH UNSUCCESSFUL');
end

        end

        % Button pushed function: SEARCHButton_5
        function SEARCHButton_5Pushed(app, event)
%git callback 5 april
txt= app.EditField_4.Value;
%theory part
if (txt=="git"||txt=="what is git"||txt=="what do you mean by git"||txt=="what is git vcs"||txt=="git vcs")
app.TextArea_5.Value ='Git is a modern and widely used distributed version control system in the world. It is developed to manage projects with high speed and efficiency. The version control system allows us to monitor and work together with our team members at the same workspace.';
elseif(txt=="what is a vcs"||txt=="vcs"||txt=="what is a versioon control system")
app.TextArea_5.Value ='A version control system (VCS) or version control software automates the process of version control. It tracks changes to a file or set of files over time so that you do not have to manage file versions manually or with custom automation scripts. A version control system keeps a complete history of your code and other files, allowing you to return to a previous version if needed. ';
elseif(txt=="why is git a vcs"||txt=="how is git a vcs"||txt=="how is git a version control system"||txt=="why is git a version control system")
app.TextArea_5.Value ='Git is a Version Control System or VCS. VCS is basically software designed to record changes within one or more files over time. It allows us to undo or to cancel all made or pending changes within one or more files. If we are working on a project with many files, VCS enables us to control the whole project.';
elseif(txt=="why study git"||txt=="features of git"||txt=="uses of git"||txt=="uses"||txt=="features")
app.TextArea_5.Value{1} ='Tracks history';
app.TextArea_5.Value{2} ='Free and open source';
app.TextArea_5.Value{3} ='Supports non-linear development';
app.TextArea_5.Value{4} ='Creates backups';
app.TextArea_5.Value{5} ='Scalable';
app.TextArea_5.Value{6} ='Supports collaboration';
app.TextArea_5.Value{7} ='Branching is easier';
app.TextArea_5.Value{8} ='Distributed development';
elseif(txt=="whati do you mean by mergeing and branching"||txt=="what is branching"||txt=="what is merging"||txt=="branching"||txt=="merging"||txt=="branching and merging")
app.TextArea_5.Value {1}= 'In Git, branches are a part of your everyday development process. Git branches are effectively a pointer to a snapshot of your changes. When you want to add a new feature or fix a bug—no matter how big or how small—you spawn a new branch to encapsulate your changes.';
app.TextArea_5.Value{2} ='Git merging combines sequences of commits into one unified history of commits. There are two main ways Git will merge: Fast Forward and Three way. Git can automatically merge commits unless there are changes that conflict in both commit sequences.';
elseif(txt=="what are git components"||txt=="commands in git"||txt=="git commands")
app.TextArea_5.Value ='Git commands are a distributed version control system for tracking changes in any set of files. They were originally designed for coordinating work among programmers who were operating source codes during software development.';
%code and command parts
%elseif(txt==" "||txt=="  ")
    % STAGE & SNAPSHOT
    % Working with snapshots and the Git staging area
elseif(txt=="git status")
app.TextArea_5.Value {1}='git status ';
app.TextArea_5.Value {2}='show modified files in working directory, staged for your next commit';
elseif(txt=="git add"||txt=="add ")
app.TextArea_5.Value {1}='git add[file]';
app.TextArea_5.Value {2}='add a file as it looks now to your next commit (stage)';
elseif(txt=="git reset "||txt=="  reset  ")
app.TextArea_5.Value {1}='git reset [file] ';
app.TextArea_5.Value {2}='unstage a file while retaining the changes in working directory';
elseif(txt=="git diff  "||txt=="diff   ")
app.TextArea_5.Value {1}='git diff ';
app.TextArea_5.Value {2}='diff of what is changed but not staged';
elseif(txt==" git diff --staged "||txt=="diff --staged  ")
app.TextArea_5.Value {1}='git diff --staged ';
app.TextArea_5.Value {2}='diff of what is staged but not yet committed';
elseif(txt==" git commit"||txt==" git commit -m “[descriptive message]” ")
app.TextArea_5.Value {1}='git commit -m "[descriptive message]” ';
app.TextArea_5.Value {2}='commit your staged content as a new commit snapshot';
%SETUP Configuring user information used across all local repositories
elseif(txt=="git config"||txt=="configuring user information ")
  app.TextArea_5.Value {1}='  git config --global user.name 1[firstname lastname]" set a name that is identifiable for credit when review version history ';
   app.TextArea_5.Value {2}=' git config --global user.email "[valid-email]” set an email address that will be associated with each history marker';
  app.TextArea_5.Value {3}='  git config --global color.ui auto set automatic command line coloring for Git for easy reviewing';
  %branch and merge
 elseif(txt=="git branch"||txt=="git merge"||txt=="git checkout")
 app.TextArea_5.Value {1}='Isolating work in branches, changing context, and integrating changes';
 app.TextArea_5.Value {2}='git branch list your branches. a * will appear next to the currently active branch ';
 app.TextArea_5.Value {3}='git branch [branch-name] create a new branch at the current commit ';
 app.TextArea_5.Value {4}='git checkout switch to another branch and check it out into your working directory ';
 app.TextArea_5.Value {5}='git merge [branch] merge the specified branchs history into the current one git log show all commits in the current branch;s history';
elseif(txt=="git log"||txt=="git diff")
app.TextArea_5.Value {1}='INSPECT & COMPARE Examining logs, diffs and object information ';
app.TextArea_5.Value {2}='git log show the commit history for the currently active branch ';
app.TextArea_5.Value {3}='git log branchB..branchA show the commits on branchA that are not on branchB ';
app.TextArea_5.Value {4}='git log --follow [file] show the commits that changed file, even across renames ';
app.TextArea_5.Value {5}='git diff branchB..branchA show the diff of what is in branchA that is not in branchB git show [SHA] show any object in Git in human-readable format';
elseif(txt==" git stash"||txt=="temporary commits")
app.TextArea_5.Value {1}='TEMPORARY COMMITS Temporarily store modified, tracked files  in order to change branches';
app.TextArea_5.Value {2}='git stash Save modified and staged changes ';
app.TextArea_5.Value {3}='git stash list list stack-order of stashed file changes'; 
app.TextArea_5.Value {4}='git stash pop write working from top of stash stack ';
app.TextArea_5.Value {5}='git stash drop discard  the changes from top of stash stack';
elseif(txt=="git init"||txt=="git clone")
app.TextArea_5.Value {1}='SETUP & INIT Configuring user information, initializing and cloning repositories';
app.TextArea_5.Value {2}='git init initialize an existing directory as a Git repository';
app.TextArea_5.Value {3}='git clone [url] retrieve an entire repository from a hosted location via URL';
elseif(txt=="git rebase"||txt=="git reset")
app.TextArea_5.Value {1}='REWRITE HISTORY Rewriting branches, updating commits and clearing history ';
app.TextArea_5.Value {2}='git rebase [branch] apply any commits of current branch ahead of specified one'; 
app.TextArea_5.Value {3}='git reset --hard [commit] clear staging area, rewrite working tree from specified commit';


else
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,' my apology , i dont have a solution for this at the momment ');
    app.TextArea_5.Value ="cant find a solution";


end 


        end

        % Button pushed function: SEARCHButton_2
        function SEARCHButton_2Pushed(app, event)
            % callback for html tab
txt= app.EditField_2.Value;
if(txt=="html"||txt=="what is html")
app.TextArea_3.Value='html is a markup language HTML (HyperText Markup Language) is the most basic building block of the Web. It defines the meaning and structure of web content';

elseif(txt=="why is html used"||txt=="use of html")
app.TextArea_3.Value{1}='html is used for web pages. ';
app.TextArea_3.Value{2}='Structuring web pages. With tags and elements, we can define the headings, paragraphs, and other contents of a web page.';
app.TextArea_3.Value{3}='Navigating the internet. ...';
app.TextArea_3.Value{4}='Embedding images and videos. ...';
app.TextArea_3.Value{5}='Improving client-side data storage and offline capabilities. ...';
app.TextArea_3.Value{6}='Game development. ...';
app.TextArea_3.Value{7}='Interacting with native APIs.';
elseif(txt=="what is a paragragph tag "||txt=="paragraph"||txt=='p')
app.TextArea_3.Value='The HTML <p> element defines a paragraph. A paragraph always starts on a new line, and browsers automatically add some white space (a margin) before and after a paragraph.';
elseif(txt=="what is bold tag"||txt=="bold")
app.TextArea_3.Value= 'HTML bold tag is represented by <b> tag. HTML <b> tag is used to display the written text in bold format. It is strictly a presentational element. ';
elseif(txt=="what is anchor tag"||txt=="anchor"||txt=='a')
app.TextArea_3.Value{1}='The <a> tag defines a hyperlink, which is used to link from one page to another.';
app.TextArea_3.Value{2}='The most important attribute of the <a> element is the href attribute, which indicates the links destination.';
app.TextArea_3.Value{3}='By default, links will appear as follows in all browsers:';
app.TextArea_3.Value{4}='An unvisited link is underlined and blue';
app.TextArea_3.Value{5}='A visited link is underlined and purple';
app.TextArea_3.Value{6}='An active link is underlined and red';
elseif(txt=="div tag"||txt=="div")
app.TextArea_3.Value= 'The <div> tag defines a division or a section in an HTML document. The <div> tag is used as a container for HTML elements - which is then styled with CSS or manipulated with JavaScript. The <div> tag is easily styled by using the class or id attribute. Any sort of content can be put inside the <div> tag!';
elseif(txt=="head tag"||txt=="head")
app.TextArea_3.Value= 'The <head> element is a container for metadata (data about data) and is placed between the <html> tag and the <body> tag. Metadata is data about the HTML document. Metadata is not displayed. Metadata typically define the document title, character set, styles, scripts, and other meta information.';
elseif(txt=="body tag"||txt=="body")
app.TextArea_3.Value= 'The <body> tag defines the documents body. The <body> element contains all the contents of an HTML document, such as headings, paragraphs, images, hyperlinks, tables, lists, etc. Note: There can only be one <body> element in an HTML document.';
elseif(txt=="title tag"||txt=="title")
app.TextArea_3.Value='The <title> tag defines the title of the document. The title must be text-only, and it is shown in the browsers title bar or in the pages tab. The <title> tag is required in HTML documents! The contents of a page title is very important for search engine optimization';
elseif(txt=="h1 tag"||txt=="h1"||txt=="h2 tag"||txt=="h2"||txt=="h3 tag"||txt=="h3"||txt=="h4 tag"||txt=="h4"||txt=="h5 tag"||txt==" h5"||txt=="h6 tag"||txt=="h6")
app.TextArea_3.Value{1}='The <h1> to <h6> tags are used to define HTML headings.';
app.TextArea_3.Value{2}='<h1> defines the most important heading. <h6> defines the least important heading.';
app.TextArea_3.Value{3}='Note: Only use one <h1> per page - this should represent the main heading/subject for the whole page. Also, do not skip heading levels - start with <h1>, then use <h2>, and so on.';
elseif(txt=="i tag"||txt=="i")
app.TextArea_3.Value='The <i> tag defines a part of text in an alternate voice or mood. The content inside is typically displayed in italic. The <i> tag is often used to indicate a technical term, a phrase from another language, a thought, a ship name, etc.';
elseif(txt=="base tag"||txt=="base")
app.TextArea_3.Value='The <base> tag specifies the base URL and/or target for all relative URLs in a document.';
elseif(txt=="main tag"||txt=="main")
app.TextArea_3.Value='<main> The <main> HTML element represents the dominant content of the <body> of a document. The main content area consists of content that is directly related to or expands upon the central topic of a document, or the central functionality of an application';
elseif(txt=="footer tag"||txt=="footer")
app.TextArea_3.Value='The <footer> tag defines a footer for a document or section. A <footer> element typically contains: ... You can have several <footer> elements in one document.';
elseif(txt=="hr tag"||txt=="hr")
app.TextArea_3.Value='<hr>: The Thematic Break (Horizontal Rule) element. The <hr> HTML element represents a thematic break between paragraph-level elements: for example, a change of scene in a story, or a shift of topic within a section.';
elseif(txt=="mark tag"||txt=="mark")
app.TextArea_3.Value='<mark>: The Mark Text element. The <mark> HTML element represents text which is marked or highlighted for reference or notation purposes due to the marked passages relevance in the enclosing context.';
elseif(txt=="strike tag"||txt=="strike")
app.TextArea_3.Value='The <s> HTML element renders text with a strikethrough, or a line through it. Use the <s> element to represent things that are no longer relevant or no longer accurate. However, <s> is not appropriate when indicating document edits; for that, use the <del> and <ins> elements, as appropriate';
elseif(txt=="u tag"||txt=="u")
app.TextArea_3.Value='The <u> HTML element represents a span of inline text which should be rendered in a way that indicates that it has a non-textual annotation. This is rendered by default as a simple solid underline, but may be altered using CSS.';
elseif(txt=="comment"||txt=="comment tag")
app.TextArea_3.Value='HTML <!--...--> Tag,The comment tag is used to insert comments in the source code. Comments are not displayed in the browsers. You can use comments to explain your code, which can help you when you edit the source code at a later date. This is especially useful if you have a lot of code.';
elseif(txt=="docktype"||txt=="document type")
app.TextArea_3.Value{1}='All HTML documents must start with a <!DOCTYPE> declaration.';

app.TextArea_3.Value{2}='The declaration is not an HTML tag. It is an "information" to the browser about what document type to expect.';

app.TextArea_3.Value{3}='In HTML 5, the declaration is simple: <!DOCTYPE html>';
elseif(txt=="script tag"||txt=="script")
app.TextArea_3.Value{1}='The HTML <script> tag is used to define a client-side script (JavaScript).';

app.TextArea_3.Value{2}='The <script> element either contains script statements, or it points to an external script file through the src attribute.';


else
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,' my apology , i dont have a solution for this at the momment ');
    app.TextArea_3.Value ="cant find a solution";


end

% app.TextArea_3.Value =txt;
        end

        % Button pushed function: CPROGRAMMINGButton
        function CPROGRAMMINGButtonPushed(app, event)
             open('notes.pdf')
        end

        % Callback function
        function quizButtonPushed(app, event)

        end

        % Button pushed function: AboutusButton
        function AboutusButtonPushed(app, event)
           
msgbox('Welcome to Code XLR8 , this application is intended to provide education , mentorship and doubt solving , choose your subject ask questions . if any problem or issue persists kindly send us a feedback.happy coding')
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'Welcome to Code XLR8 , this application is intended to provide education , mentorship and doubt solving , choose your subject ask questions . if any problem or issue persists kindly send us a feedback ,happy coding');
        end

        % Button pushed function: TermsandconditionsButton
        function TermsandconditionsButtonPushed(app, event)
                       
msgbox('This application is for education purpose only , copying or re use of data in any form is strictly prohibitted ')
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'This application is for education purpose only , copying or re use of data in any form is strictly prohibitted ');
        end

        % Button pushed function: ContactusButton
        function ContactusButtonPushed(app, event)
           msgbox('Thank you so much for showing interest, you can contact us by sending an email at tripathiprachi007@gmail.com')
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'Thank you so much for showing interest, you can contact us by sending an email at tripathiprachi007@gmail.com'); 
        end

        % Button pushed function: FeedbackButton
        function FeedbackButtonPushed(app, event)
             msgbox('Your feedback holds importance for us to improve and provide you with better updates in the future , kindly send us an email for any bug reports errors or suggestions from your end, you can contact us by sending an email at tripathiprachi007@gmail.com')
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'Your feedback holds importance for us to improve and provide you with better updates in the future , kindly send us an email for any bug reports errors or suggestions from your end, you can contact us by sending an email at tripathiprachi007@gmail.com'); 
        end

        % Button pushed function: SEARCHButton
        function SEARCHButtonPushed(app, event)
            %home tab callback.....
            txt= app.EditField.Value;
if(txt=="what is code xlr8"||txt=="code xlr8")
    app.TextArea_2.Value ='CODE XLR8 is a educational mentor made to resolve your doubts and provide assistance for your programming journey';

elseif(txt=="what is the version"||txt=="current version"||txt=="version")
app.TextArea_2.Value ='VERRSION 1.0';
elseif(txt=="who made code xlr8"||txt=="creator")
app.TextArea_2.Value ='PRACHI TRIPATHI';
elseif(txt=="use of code xlr8"||txt=="use")
    app.TextArea_2.Value='SYSTEM CAN BE USED FOR LEARNING, DOUBT SOLVING, ASSISTANCE, QUICK REFERENCE, MENTOR';
    else
    NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,' my apology , i dont have a solution for this at the momment ');
    app.TextArea_2.Value ="cant find a solution";

end
            % app.TextArea_2.Value =txt;
        end

        % Button pushed function: HTMLButton
        function HTMLButtonPushed(app, event)
            open('html.pdf')
        end

        % Button pushed function: GITButton
        function GITButtonPushed2(app, event)
            open('git.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CodeXLR8Label = uilabel(app.UIFigure);
            app.CodeXLR8Label.Text = '  Code XLR8';
            app.ApplicationdesignedanddevelopedbyPrachiTripathiLabel = uilabel(app.UIFigure);
            app.ApplicationdesignedanddevelopedbyPrachiTripathiLabel.Text = 'Application designed and developed by-Prachi Tripathi';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.EditField = uieditfield(app.HOMETab, 'text');
            app.EditField.Placeholder = 'HOW MAY I HELP YOU TODAY.';
            app.TextArea_2 = uitextarea(app.HOMETab);
            app.TextArea_2.Placeholder = 'WELCOME TO CODE XLR8  .                                                                                                                                                                                                                                                                                                          KINDLY SELECT YOUR SUBJECT  FROM  ABOVE TITLEBAR.                                                                                                                                                                                                           IF ANY QUESTIONS FROM THE SYSTEM  , YOU CAN ASK HERE.                                                                                                                                                                              HAPPY CODING.';
            app.SEARCHButton = uibutton(app.HOMETab, 'push');
            app.SEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButtonPushed, true);
            app.SEARCHButton.Text = 'SEARCH';
            app.CPROGRAMMINGTab = uitab(app.TabGroup);
            app.CPROGRAMMINGTab.Title = 'C PROGRAMMING';
            app.searchbarEditFieldLabel = uilabel(app.CPROGRAMMINGTab);
            app.searchbarEditFieldLabel.Text = 'searchbar';
            app.searchbarEditField = uieditfield(app.CPROGRAMMINGTab, 'text');
            app.searchbarEditField.Placeholder = 'Enter your question here.';
            app.resultspaceEditFieldLabel = uilabel(app.CPROGRAMMINGTab);
            app.resultspaceEditFieldLabel.Text = 'resultspace';
            app.resultspaceEditField = uieditfield(app.CPROGRAMMINGTab, 'text');
            app.resultspaceEditField.ValueChangedFcn = createCallbackFcn(app, @resultspaceEditFieldValueChanged, true);
            app.SearchButton = uibutton(app.CPROGRAMMINGTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.TextAreaLabel = uilabel(app.CPROGRAMMINGTab);
            app.TextAreaLabel.Text = 'Text Area      ';
            app.TextArea = uitextarea(app.CPROGRAMMINGTab);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.HTMLTab = uitab(app.TabGroup);
            app.HTMLTab.Title = 'HTML';
            app.EditField_2 = uieditfield(app.HTMLTab, 'text');
            app.SEARCHButton_2 = uibutton(app.HTMLTab, 'push');
            app.SEARCHButton_2.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButton_2Pushed, true);
            app.SEARCHButton_2.Text = 'SEARCH';
            app.TextArea_3 = uitextarea(app.HTMLTab);
            app.GITTab = uitab(app.TabGroup);
            app.GITTab.Title = 'GIT';
            app.EditField_4 = uieditfield(app.GITTab, 'text');
            app.TextArea_5 = uitextarea(app.GITTab);
            app.SEARCHButton_5 = uibutton(app.GITTab, 'push');
            app.SEARCHButton_5.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButton_5Pushed, true);
            app.SEARCHButton_5.Text = 'SEARCH';
            app.BookshelfTab = uitab(app.TabGroup);
            app.BookshelfTab.Title = 'Bookshelf';
            app.CPROGRAMMINGButton = uibutton(app.BookshelfTab, 'push');
            app.CPROGRAMMINGButton.ButtonPushedFcn = createCallbackFcn(app, @CPROGRAMMINGButtonPushed, true);
            app.CPROGRAMMINGButton.Icon = fullfile(pathToMLAPP, 'BOOK (2).jpeg');
            app.CPROGRAMMINGButton.Text = 'C PROGRAMMING';
            app.HTMLButton = uibutton(app.BookshelfTab, 'push');
            app.HTMLButton.ButtonPushedFcn = createCallbackFcn(app, @HTMLButtonPushed, true);
            app.HTMLButton.Icon = fullfile(pathToMLAPP, 'BOOK (2).jpeg');
            app.HTMLButton.Text = 'HTML';
            app.GITButton = uibutton(app.BookshelfTab, 'push');
            app.GITButton.ButtonPushedFcn = createCallbackFcn(app, @GITButtonPushed2, true);
            app.GITButton.Icon = fullfile(pathToMLAPP, 'BOOK (2).jpeg');
            app.GITButton.Text = 'GIT';
            app.INTRODUCTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.INTRODUCTIONButtonGroup.Title = 'INTRODUCTION';
            app.ADMINButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.ADMINButton.ButtonPushedFcn = createCallbackFcn(app, @ADMINButtonPushed, true);
            app.ADMINButton.Text = 'ADMIN';
            app.FeedbackButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.HomeButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.HomeButton.Text = 'Home';
            app.AboutusButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.AboutusButton.ButtonPushedFcn = createCallbackFcn(app, @AboutusButtonPushed, true);
            app.AboutusButton.Text = 'About us';
            app.TermsandconditionsButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.TermsandconditionsButton.ButtonPushedFcn = createCallbackFcn(app, @TermsandconditionsButtonPushed, true);
            app.TermsandconditionsButton.Text = 'Terms and conditions';
            app.ContactusButton = uibutton(app.INTRODUCTIONButtonGroup, 'push');
            app.ContactusButton.ButtonPushedFcn = createCallbackFcn(app, @ContactusButtonPushed, true);
            app.ContactusButton.Text = 'Contact us';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end