classdef app20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.StateButton
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UserPrompt='login first';
TitleBar='text to speech';
TitleBar.Size=60;
% String='MATLAB IS BEST';
UserInput= inputdlg (UserPrompt , TitleBar , 1 , string);
if isempty(UserInput)
return;
end
UserInput=char(UserInput);
% NET.addAssembly('System.Speech');
% obj=System.Speech.Synthesis.SpeechSynthesizer;
% obj.Volume=100;
% Speak(obj,UserInput);
b='007';
c=strcmp(UserInput,b);
if c==1
%     msgbox('success');
msgbox(['WELCOME ADMIN .' ...
'VERSION=1.0' ...
'DATE CREATED 15/04/2003' ...
'SUBJECTS =C,HTML,GIT,CSS'])
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH SUCCESSFUL');
else
msgbox('failed');
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH UNSUCCESSFUL');
delete(app);
end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
           
  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)


        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            UserPrompt='login first';
TitleBar='text to speech';
% String='MATLAB IS BEST';
UserInput= inputdlg (UserPrompt , TitleBar , 1 , string);
if isempty(UserInput)
return;
end
UserInput=char(UserInput);
% NET.addAssembly('System.Speech');
% obj=System.Speech.Synthesis.SpeechSynthesizer;
% obj.Volume=100;
% Speak(obj,UserInput);
b='007';
c=strcmp(UserInput,b);
if c==1
%     msgbox('success');
msgbox(['WELCOME ADMIN .' ...
'VERSION=1.0' ...
'DATE CREATED 15/04/2003' ...
'SUBJECTS =C,HTML,GIT,CSS'])
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH SUCCESSFUL');
else
msgbox('failed');
NET.addAssembly('System.Speech');
obj=System.Speech.Synthesis.SpeechSynthesizer;
obj.Volume=100;
Speak(obj,'PASSWORD MATCH UNSUCCESSFUL');
delete(app);
end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
      
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app20

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end